classdef phase2_sonhal_INSHALLAH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        realTime                       matlab.ui.container.Tab
        STARTButton                    matlab.ui.control.Button
        STOPButton                     matlab.ui.control.Button
        RecordDurationsEditFieldLabel  matlab.ui.control.Label
        recordDurationText             matlab.ui.control.NumericEditField
        STARTRECORDINGButton           matlab.ui.control.Button
        situationAndError              matlab.ui.control.Label
        plotEMG                        matlab.ui.control.UIAxes
        plotFFT                        matlab.ui.control.UIAxes
        analyzing                      matlab.ui.container.Tab
        FilterTypeKnobLabel            matlab.ui.control.Label
        FilterTypeKnob                 matlab.ui.control.DiscreteKnob
        PassTypeKnobLabel              matlab.ui.control.Label
        PassTypeKnob                   matlab.ui.control.DiscreteKnob
        EnterCutoffFrequenciesLabel    matlab.ui.control.Label
        cutoffFrequency                matlab.ui.control.NumericEditField
        filterAnalyze                  matlab.ui.control.Button
        ChoosefiltertypeandPasstypeLabel  matlab.ui.control.Label
        EnterCutoffFrequenciesLabel_2  matlab.ui.control.Label
        cutoffFrequency_2              matlab.ui.control.NumericEditField
        OrderofthefilterLabel          matlab.ui.control.Label
        filterOrder                    matlab.ui.control.NumericEditField
        RippleLabel                    matlab.ui.control.Label
        filterRipple                   matlab.ui.control.NumericEditField
        constructionInfo               matlab.ui.control.Label
        dispHideConstruction           matlab.ui.control.Image
        plotRecordedEMG                matlab.ui.control.UIAxes
        plotRecordedFFT                matlab.ui.control.UIAxes
        plotRecordedFilteredEMG        matlab.ui.control.UIAxes
        RmsTab                         matlab.ui.container.Tab
        RunButton                      matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        fs = 1600;
        startStop;
        a;
        b;
        startRecording = 0;
        recordDuration;
        recordStartTime;
        recordedData;
        recordedTime;
        data;
        time;
        arduino;
        uu=0;
        dataFFT;
        pass_type;
        filter_type;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
 
        end

        % Value changed function: FilterTypeKnob
        function FilterTypeKnobValueChanged(app, event)
            app.filter_type = app.FilterTypeKnob.Value;
        end

        % Value changed function: PassTypeKnob
        function PassTypeKnobValueChanged(app, event)
            app.pass_type = app.PassTypeKnob.Value;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            clear app.arduino;
            app.startStop = 0;
            %time_variable = 0;
            j=1;
            x = 501;  
            app.data = zeros(1,x-1);
            app.time = zeros(1,x-1);
            app.dataFFT = zeros(1,8000);
            app.arduino = serialport('COM8',2000000);
            i_fft = 1;
            tic;
            while app.startStop<1
                i = 1;
                while i<x
                    serialLine = readline(app.arduino);
                    splittedValues = split(serialLine,' ');   
                    data_variable = str2double(splittedValues(1));
                    time_variable = str2double(splittedValues(2))*10^-6;
                    app.data(i)=(data_variable-589.4)*(5000/1024);
                    app.time(i) = time_variable;
                    app.dataFFT(i_fft)=data_variable;
                    if app.startRecording==1
                        if toc<(app.recordDuration + app.recordStartTime)
                            app.recordedData(j) = (data_variable-614.4)*(5000/1024);
                            app.recordedTime(j) = time_variable;
                            j = j+1;
                        end
                    end
                    i = i+1;
                    i_fft=i_fft+1;
                end
                if i==x
                    plot(app.plotEMG,app.time,app.data);
                    drawnow;
                    hold(app.plotEMG,'on');
                   xlim(app.plotEMG,[app.time(x-1)-10 app.time(x-1)]);
                   daspect(app.plotEMG,[1 100 100]);    
                end
                if mod(i_fft,8000)==1
                    F = abs(fft(app.dataFFT,app.fs+1));
                    Fs = F(1:app.fs/2+1)/(app.fs/2);
                    plot(app.plotFFT,0:app.fs/2,Fs);
                    ylim(app.plotFFT,[0 1]);
                    drawnow;
                    i_fft = 1;
                end
            end         
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.startStop = 2;
        end

        % Value changed function: recordDurationText
        function recordDurationTextValueChanged(app, event)
            app.recordDuration = app.recordDurationText.Value;
        end

        % Button pushed function: STARTRECORDINGButton
        function STARTRECORDINGButtonPushed(app, event)
            app.startRecording = 1;
            app.recordStartTime = toc;
        end

        % Image clicked function: dispHideConstruction
        function dispHideConstructionImageClicked(app, event)
            app.uu = app.uu+1;
            if mod(app.uu,2)==1
                app.constructionInfo.Visible = "on";
            else
                app.constructionInfo.Visible = "off";
            end
        end

        % Button pushed function: filterAnalyze
        function filterAnalyzeButtonPushed(app, event)
            i = 0;
            % Cutoff freq.s,  ripple and order
            cutoff_1 = app.cutoffFrequency.Value;
            cutoff_2 = app.cutoffFrequency_2.Value;
            wc_1 = cutoff_1/(app.fs/2);
            wc_2 = cutoff_2/(app.fs/2);
            order = app.filterOrder.Value;
            ripple = app.filterRipple.Value;
            % Determining which filter was chosen
            switch app.pass_type
                case 'Low-Pass'
                    switch app.filter_type
                        case 'Butterworth'
                            [app.b, app.a] = butter(int8(order),wc_1, "low");
                        case 'Chebychev'
                            [app.b, app.a] = cheby1(int8(order), ripple, wc_1, 'low');
                        case 'Elliptic'
                            [app.b, app.a] = ellip(int8(order), ripple, ripple+0.1,wc_1, 'low');
                    end
                case 'High-Pass'
                     switch app.filter_type
                         case 'Butterworth'
                              [app.b, app.a] = butter(int8(order), wc_1, "high");
                         case 'Chebychev'
                             [app.b, app.a] = cheby1(int8(order), ripple, wc_1,"high");
                         case 'Elliptic'
                             [app.b, app.a] = ellip(int8(order), ripple, ripple+0.1, wc_1, 'high');
                     end
                case 'Band-Pass'   
                     switch app.filter_type
                         case 'Butterworth'
                              [app.b, app.a] = butter(int8(order)/2, [wc_1 wc_2],"bandpass");
                         case 'Chebychev'
                              [app.b, app.a] = cheby1(int8(order),ripple, [wc_1 wc_2], "bandpass");
                         case 'Elliptic'
                             [app.b, app.a] = ellip(int8(order), ripple, ripple+0.1, [wc_1 wc_2], "bandpass");
                     end
            end
            %FFT of the signal
            Fn = abs(fft(app.recordedData,app.fs+1));
            Fns = Fn(1:app.fs/2+1)/app.fs;
            %Filtering; 
            FilteredEMGSignal = filter(app.b, app.a, app.recordedData);
            app.fs  =1600;
            %RMS calculation;
            N = 300; % Window
            t_2 = (0:(length(FilteredEMGSignal)-N)-1)/app.fs; % Time component
            sizeemg = size(FilteredEMGSignal,2);
            movingrms = zeros(sizeemg-N,1);
            for i=1:sizeemg-N
             wins = FilteredEMGSignal(i:i+N-1);
             movingrms(i,1) = sqrt(sum(wins.^2)/N);
            end
            plot(app.UIAxes,t_2',movingrms);
            hold(app.UIAxes, 'on');
            sz = length(t_2);
            threshhold = 20; %12.48; % The limit value we apply to determine the contraction time
            y = threshhold*ones(sz); % Threshhold line to see the limit
            plot(app.UIAxes,t_2,y); 
            %Construction times Calculation
            i = 0;
            threshhold = 11.75;
            count = 1;
            pp = 1;
            xx = 1;
            cc = 1;
            ll = 1;
            sn = []; % Sample number for each contraction
            startTime = [];
            stopTime = [];
            for n=1:numel(movingrms) % Returns the number of elements
                 if movingrms(n) > threshhold
                     if count == 1
                         count = count + 1;
                         Ini_time = n/app.fs; % It gives the time values at the start of the contraction
                         k1 = n; % It gives the sample numbers at the start of the contraction
                         startTime(xx) = n/app.fs; % Gives the start times as an array
                         xx = xx + 1;
                     end
                 elseif movingrms(n) < threshhold
                     if count == 2
                         y1 = ['Time when the contraction ends is',': ',num2str(n/app.fs),' s'];
                         disp(y1);
                         count = 1;
                         End_time = n/app.fs; % It gives the time values at the end of the contraction
                         k2 = n; % It gives the sample numbers at the end of the contraction
                         stopTime(cc) = n/app.fs; % % Gives the end times as an array
                         cc = cc + 1;
                         vns = End_time - Ini_time; % Time betweenthe initiation of the contraction until the muscle is relaxed
                         sn(ll) = vns;
                         ll = ll + 1;
                         rr(pp,1) = vns;
                         Moc = movingrms(k1:k2,1); % moment ofcontraction
                         Svc(pp,1) = sum(Moc); % sum of values incontraction
                     end
                 end
            end
            
            %Power Calculation
%             i = 0;
%             avaragePowerSize = length(startTime); % Thelength of the contraction values when the contraction starts
%             avaragePowers= ones(1,avaragePowerSize); % Wecreated the array to calculate avarage power
%             for i = 1:length(startTime)
%                  calculation_n = round(startTime(i)*app.fs); % Itgives the sample numbers.(Round was used for ease of operation)
%                  start_n = startTime(i)*app.fs;
%                  end_n = stopTime(i)*app.fs;
%                  sum_of_values = 0;
%                  while (calculation_n <= end_n)
%                      sum_of_values = sum_of_values + (movingrms(calculation_n))^2; % To calculate the average power we squared each value at the moment of contraction. Then we summed these values
%                      calculation_n = calculation_n + 1;
%                  end
%                  diff_n = end_n - start_n; % Number of samples at contraction time
%                  avaragePowers(i) = sum_of_values/diff_n; % Average power values
%             end
            
            %Displaying construction times in a window
%             constructionText = strings(length(stopTime),1);
%             for vv = 1:length(stopTime)
%                 constructionText(vv) =["A construction occured between ", num2str(startTime(vv))," and ", num2str(stopTime(vv))," seconds."];
% %             end
%             for i =1:length(stopTime)
%                 xValues(i) = app.recordedData(startTime(i)*app.fs);
%                 xTimes(i) = startTime(i);
%             end
%             A = [""];
%             for vv = 1:length(startTime)
%                 A = [string + A + newline + constructionText(vv)];
%             end
% %                 app.constructionInfo.Text = A;
%             app.constructionInfo.Text = constructionText;           
            plot(app.plotRecordedEMG, app.recordedTime, app.recordedData);
            xlim(app.plotRecordedEMG,[app.recordedTime(1) app.recordedTime(length(app.recordedTime))]); 
            ylim(app.plotRecordedEMG,[-200 200]);
            
%             for i = 1:length(stopTime)
%                 plot(app.plotRecordedEMG,xTimes(i),xValues(i),'X','r');
%             end
            plot(app.plotRecordedFFT, 0:app.fs/2,Fns);
            ylim(app.plotRecordedFFT,[0 1.5]);
            plot(app.plotRecordedFilteredEMG, app.recordedTime,FilteredEMGSignal);
            ylim(app.plotRecordedFilteredEMG,[-150 150]);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            FilteredEMGSignal = filter(app.b, app.a, app.recordedData);
            %RMS calculation;
            N = 300; % Window
            t_2 = (0:(length(FilteredEMGSignal)-N)-1)/app.fs; % Time component
            sizeemg = size(FilteredEMGSignal,2);
            movingrms = zeros(sizeemg-N,1);
            for i=1:sizeemg-N
             wins = FilteredEMGSignal(i:i+N-1);
             movingrms(i,1) = sqrt(sum(wins.^2)/N);
            end
            plot(app.UIAxes,t_2',movingrms);
            hold(app.UIAxes, 'on');
            sz = length(t_2);
            threshhold = 11.75; %12.48; % The limit value we apply to determine the contraction time
            y = threshhold*ones(sz); % Threshhold line to see the limit
            plot(app.UIAxes,t_2,y);
            
            %Construction times Calculation
            i = 0;
            threshhold = 11.75;
            count = 1;
            pp = 1;
            xx = 1;
            cc = 1;
            ll = 1;
            sn = []; % Sample number for each contraction
            startTime = [];
            stopTime = [];
            for n=1:numel(movingrms) % Returns the number of elements
                 if movingrms(n) > threshhold
                     if count == 1
                         count = count + 1;
                         Ini_time = n/app.fs; % It gives the time values at the start of the contraction
                         k1 = n; % It gives the sample numbers at the start of the contraction
                         startTime(xx) = n/app.fs; % Gives the start times as an array
                         xx = xx + 1;
                     end
                 elseif movingrms(n) < threshhold
                     if count == 2
                         y1 = ['Time when the contraction ends is',': ',num2str(n/app.fs),' s'];
                         disp(y1);
                         count = 1;
                         End_time = n/app.fs; % It gives the time values at the end of the contraction
                         k2 = n; % It gives the sample numbers at the end of the contraction
                         stopTime(cc) = n/app.fs; % % Gives the end times as an array
                         cc = cc + 1;
                         vns = End_time - Ini_time; % Time betweenthe initiation of the contraction until the muscle is relaxed
                         sn(ll) = vns;
                         ll = ll + 1;
                         rr(pp,1) = vns;
                         Moc = movingrms(k1:k2,1); % moment ofcontraction
                         Svc(pp,1) = sum(Moc); % sum of values incontraction
                     end
                 end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.realTime = uitab(app.TabGroup);
            app.realTime.Title = 'Real Time';
            app.STARTButton = uibutton(app.realTime, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.realTime, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.RecordDurationsEditFieldLabel = uilabel(app.realTime);
            app.RecordDurationsEditFieldLabel.Text = {'Record '; 'Duration (s)'};
            app.recordDurationText = uieditfield(app.realTime, 'numeric');
            app.recordDurationText.ValueChangedFcn = createCallbackFcn(app, @recordDurationTextValueChanged, true);
            app.STARTRECORDINGButton = uibutton(app.realTime, 'push');
            app.STARTRECORDINGButton.ButtonPushedFcn = createCallbackFcn(app, @STARTRECORDINGButtonPushed, true);
            app.STARTRECORDINGButton.Text = {'START'; 'RECORDING'};
            app.situationAndError = uilabel(app.realTime);
            app.situationAndError.Text = '';
            app.plotEMG = uiaxes(app.realTime);
            title(app.plotEMG, 'Raw EMG Signal')
            xlabel(app.plotEMG, 'Time (s)')
            ylabel(app.plotEMG, 'Voltage (mV)')
            zlabel(app.plotEMG, 'Z')
            app.plotFFT = uiaxes(app.realTime);
            title(app.plotFFT, 'Fourier Transform of EMG Signal')
            xlabel(app.plotFFT, 'Frequence (Hz)')
            ylabel(app.plotFFT, 'Amplitude')
            zlabel(app.plotFFT, 'Z')
            app.analyzing = uitab(app.TabGroup);
            app.analyzing.Title = 'Filtering and analyzing';
            app.FilterTypeKnobLabel = uilabel(app.analyzing);
            app.FilterTypeKnobLabel.Text = 'Filter Type';
            app.FilterTypeKnob = uiknob(app.analyzing, 'discrete');
            app.FilterTypeKnob.Items = {'Off', 'Butterworth', 'Chebychev', 'Elliptic'};
            app.FilterTypeKnob.ValueChangedFcn = createCallbackFcn(app, @FilterTypeKnobValueChanged, true);
            app.PassTypeKnobLabel = uilabel(app.analyzing);
            app.PassTypeKnobLabel.Text = 'Pass Type';
            app.PassTypeKnob = uiknob(app.analyzing, 'discrete');
            app.PassTypeKnob.Items = {'Off', 'Low-Pass', 'High-Pass', 'Band-Pass'};
            app.PassTypeKnob.ValueChangedFcn = createCallbackFcn(app, @PassTypeKnobValueChanged, true);
            app.EnterCutoffFrequenciesLabel = uilabel(app.analyzing);
            app.EnterCutoffFrequenciesLabel.Text = 'Enter Cut-off Frequencies';
            app.cutoffFrequency = uieditfield(app.analyzing, 'numeric');
            app.filterAnalyze = uibutton(app.analyzing, 'push');
            app.filterAnalyze.ButtonPushedFcn = createCallbackFcn(app, @filterAnalyzeButtonPushed, true);
            app.filterAnalyze.Text = 'Filter and analyze';
            app.ChoosefiltertypeandPasstypeLabel = uilabel(app.analyzing);
            app.ChoosefiltertypeandPasstypeLabel.Text = 'Choose filter type and Pass type ';
            app.EnterCutoffFrequenciesLabel_2 = uilabel(app.analyzing);
            app.EnterCutoffFrequenciesLabel_2.Text = 'Enter Cut-off Frequencies';
            app.cutoffFrequency_2 = uieditfield(app.analyzing, 'numeric');
            app.OrderofthefilterLabel = uilabel(app.analyzing);
            app.OrderofthefilterLabel.Text = 'Order of the filter';
            app.filterOrder = uieditfield(app.analyzing, 'numeric');
            app.RippleLabel = uilabel(app.analyzing);
            app.RippleLabel.Text = 'Ripple';
            app.filterRipple = uieditfield(app.analyzing, 'numeric');
            app.constructionInfo = uilabel(app.analyzing);
            app.constructionInfo.Visible = 'off';
            app.constructionInfo.Text = 'sdfsasfdsaf';
            app.dispHideConstruction = uiimage(app.analyzing);
            app.dispHideConstruction.ImageClickedFcn = createCallbackFcn(app, @dispHideConstructionImageClicked, true);
            app.plotRecordedEMG = uiaxes(app.analyzing);
            title(app.plotRecordedEMG, 'Recorded Raw EMG Signal')
            xlabel(app.plotRecordedEMG, 'Time (s)')
            ylabel(app.plotRecordedEMG, 'Voltage (mV)')
            zlabel(app.plotRecordedEMG, 'Z')
            app.plotRecordedFFT = uiaxes(app.analyzing);
            title(app.plotRecordedFFT, 'Recorded Signal''s Fourier Transform')
            xlabel(app.plotRecordedFFT, 'Frequency (Hz)')
            ylabel(app.plotRecordedFFT, 'Amplitude')
            zlabel(app.plotRecordedFFT, 'Z')
            app.plotRecordedFilteredEMG = uiaxes(app.analyzing);
            title(app.plotRecordedFilteredEMG, 'Filtered EMG Signal')
            xlabel(app.plotRecordedFilteredEMG, 'Time (s)')
            ylabel(app.plotRecordedFilteredEMG, 'Voltage (mV)')
            zlabel(app.plotRecordedFilteredEMG, 'Z')
            app.RmsTab = uitab(app.TabGroup);
            app.RmsTab.Title = 'Rms';
            app.RunButton = uibutton(app.RmsTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UIAxes = uiaxes(app.RmsTab);
            title(app.UIAxes, 'RMS Graph')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Magnitude')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = phase2_sonhal_INSHALLAH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end